classdef dtmfApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CalibrateThresholdButton    matlab.ui.control.Button
        nChannelsDropDown           matlab.ui.control.DropDown
        nChannelsDropDownLabel      matlab.ui.control.Label
        nBitsDropDown               matlab.ui.control.DropDown
        nBitsDropDownLabel          matlab.ui.control.Label
        DTMFDecoderLabel            matlab.ui.control.Label
        RecorderSettingsLabel       matlab.ui.control.Label
        DurationLabel               matlab.ui.control.Label
        FsLabel                     matlab.ui.control.Label
        ThresholdLabel              matlab.ui.control.Label
        SingleAudioRecordDurationEditField  matlab.ui.control.NumericEditField
        SingleAudioRecordDurationEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        CurrentlypressedbuttonEditField  matlab.ui.control.EditField
        CurrentlypressedbuttonEditFieldLabel  matlab.ui.control.Label
        UIAxesTime                  matlab.ui.control.UIAxes
        UIAxesDFT                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        recDuration = 0.39;          % for recording object
        fs = 16000;
        recNBits = 16;
        recNChannels = 1;
        
        audioThreshold = 0.5;        % for signal processing

        recordingEnabled = false;   % for stopping dtmf's while loop
        pressedButton = 'n';
    end

    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.audioThreshold = app.ThresholdEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            import java.awt.Robot;
            import java.awt.event.*;
            robot = Robot();
            robot.delay(2000);
            
            if ~app.recordingEnabled
                app.recordingEnabled = true;
                app.CurrentlypressedbuttonEditField.Value = "";
                app.StartButton.Text = "Stop";
                
                % Turn off other components
                app.ThresholdEditField.Editable = "off";
                app.SamplingFrequencyEditField.Editable = "off";
                app.SingleAudioRecordDurationEditField.Editable = "off";
                app.nBitsDropDown.Editable = "off";
                app.nChannelsDropDown.Editable = "off";
                app.CalibrateThresholdButton.Enable = "off";
                
                recObj = audiorecorder(app.fs, app.recNBits, app.recNChannels);

                while app.recordingEnabled
                    %% Record audio
                    recordblocking(recObj, app.recDuration);
                    %% Set signal parameters
                    audio = getaudiodata(recObj);
                    
                    dt = 1/app.fs;
                    s = app.recDuration/dt;
                    timeVector = 0:dt:app.recDuration-dt;
                    
                    %% Apply Groetzel algorithm
                    dtmfFrequencies = [697 770 852 941 1209 1336 1477];
                    freqIndices = round(dtmfFrequencies/app.fs*s) + 1;   
                    dftAudio = goertzel(audio, freqIndices);
                    dftAudio = abs(dftAudio');
                    
                    %% Get two frequencies with the greatest magnitude
                    [~, greatestLowFreqPosition] = max(dftAudio(1:4));
                    [~, greatestHighFreqPosition] = max(dftAudio(5:7));
                    greatestHighFreqPosition = greatestHighFreqPosition + 4;
                    
                    %% Check threshold
                    freqAnswers = zeros(1, 7);
                    freqAnswers(greatestLowFreqPosition) = dftAudio(greatestLowFreqPosition) >= app.audioThreshold;
                    freqAnswers(greatestHighFreqPosition) = dftAudio(greatestHighFreqPosition) >= app.audioThreshold;
                    freqAnswers = double(freqAnswers);
                    
                    %% Get output
                    freqPossibleAnswers = [
                       %[697 770 852 941 1209 1336 1477]
                        [1   0   0   0   1    0    0];    % 1
                        [1   0   0   0   0    1    0];    % 2
                        [1   0   0   0   0    0    1];    % 3
                        [0   1   0   0   1    0    0];    % 4
                        [0   1   0   0   0    1    0];    % 5
                        [0   1   0   0   0    0    1];    % 6
                        [0   0   1   0   1    0    0];    % 7
                        [0   0   1   0   0    1    0];    % 8
                        [0   0   1   0   0    0    1];    % 9
                        [0   0   0   1   1    0    0];    % *
                        [0   0   0   1   0    1    0];    % 0
                        [0   0   0   1   0    0    1]];   % #

                    % Pause variable
                    pause_var = 1.5;
                    
                    % freqAnswers has two '1' - button was pressed
                    if freqAnswers == freqPossibleAnswers(1, 1:end)
                        app.pressedButton = '1';
                        robot.mouseMove(300, 540);
                    elseif freqAnswers == freqPossibleAnswers(2, 1:end)
                        app.pressedButton = '2';
                        robot.keyPress(KeyEvent.VK_W);
                        pause(pause_var);
                        robot.keyRelease(KeyEvent.VK_W);
                    elseif freqAnswers == freqPossibleAnswers(3, 1:end)
                        app.pressedButton = '3';
                        robot.mouseMove(1800, 540);
                    elseif freqAnswers == freqPossibleAnswers(4, 1:end)
                        app.pressedButton = '4';
                        robot.keyPress(KeyEvent.VK_A);
                        pause(pause_var);
                        robot.keyRelease(KeyEvent.VK_A);
                    elseif freqAnswers == freqPossibleAnswers(5, 1:end)
                        app.pressedButton = '5';
                    elseif freqAnswers == freqPossibleAnswers(6, 1:end)
                        app.pressedButton = '6';
                        robot.keyPress(KeyEvent.VK_D);
                        pause(pause_var);
                        robot.keyRelease(KeyEvent.VK_D);
                    elseif freqAnswers == freqPossibleAnswers(7, 1:end)
                        app.pressedButton = '7';
                    elseif freqAnswers == freqPossibleAnswers(8, 1:end)
                        app.pressedButton = '8';
                        robot.keyPress(KeyEvent.VK_S);
                        pause(pause_var);
                        robot.keyRelease(KeyEvent.VK_S);
                    elseif freqAnswers == freqPossibleAnswers(9, 1:end)
                        app.pressedButton = '9';
                    elseif freqAnswers == freqPossibleAnswers(10, 1:end)
                        app.pressedButton = '*';
                        robot.mousePress(java.awt.event.InputEvent.BUTTON1_DOWN_MASK);
                        pause(1);
                        robot.mouseRelease(java.awt.event.InputEvent.BUTTON1_DOWN_MASK);
                    elseif freqAnswers == freqPossibleAnswers(11, 1:end)
                        app.pressedButton = '0';
                    elseif freqAnswers == freqPossibleAnswers(12, 1:end)
                        app.pressedButton = '#';
                        robot.mousePress(java.awt.event.InputEvent.BUTTON3_DOWN_MASK);
                        pause(0.5);
                        robot.mouseRelease(java.awt.event.InputEvent.BUTTON3_DOWN_MASK);
                    else
                        app.pressedButton = 'n'; % none button was pressed
                    end
                    
                    %% Display answer
                    % Time plot
                    maxScaleValue = 1.2;
                    app.UIAxesTime.XTick = linspace(0, app.recDuration, 5);
                    app.UIAxesTime.YTick = linspace(-maxScaleValue, maxScaleValue, 7);
                    app.UIAxesTime.YLim = [-maxScaleValue maxScaleValue];
                    plot(app.UIAxesTime, timeVector, audio)
                    
                    % FFT plot
                    app.UIAxesDFT.XTick = dtmfFrequencies;
                    app.UIAxesDFT.XLim = [600 1600];
                    app.UIAxesDFT.YTick = [1, 10, 100, 1000, 10000];
                    app.UIAxesDFT.YLim = [0 10000];
                    stem(app.UIAxesDFT, dtmfFrequencies, abs(dftAudio))
                    hold(app.UIAxesDFT, 'on');
                    plot(app.UIAxesDFT, [0 2000], [app.audioThreshold app.audioThreshold], 'r--')
                    hold(app.UIAxesDFT, 'off');
                    
                    % Displaying char
                    if app.pressedButton ~= 'n'
                        app.CurrentlypressedbuttonEditField.Value = app.pressedButton;
                    else
                        app.CurrentlypressedbuttonEditField.Value = '-';
                    end
                    pause(0.01)
                end
            else
                app.recordingEnabled = false;
                app.StartButton.Text = "Start";

                % Turn on other components
                app.ThresholdEditField.Editable = "on";
                app.SamplingFrequencyEditField.Editable = "on";
                app.SingleAudioRecordDurationEditField.Editable = "on";
                app.nBitsDropDown.Editable = "on";
                app.nChannelsDropDown.Editable = "on";
                app.CalibrateThresholdButton.Enable = "on";
            end
        end

        % Value changed function: SamplingFrequencyEditField
        function SamplingFrequencyEditFieldValueChanged(app, event)
            app.fs = app.SamplingFrequencyEditField.Value;
        end

        % Value changed function: SingleAudioRecordDurationEditField
        function SingleAudioRecordDurationEditFieldValueChanged(app, event)
            app.recDuration = app.SingleAudioRecordDurationEditField.Value;
        end

        % Value changed function: nChannelsDropDown
        function nChannelsDropDownValueChanged(app, event)
            app.recNChannels = app.nChannelsDropDown.Value;
        end

        % Value changed function: nBitsDropDown
        function nBitsDropDownValueChanged(app, event)
            app.recNBits = app.nBitsDropDown.Value;
        end

        % Button pushed function: CalibrateThresholdButton
        function CalibrateThresholdButtonPushed(app, event)
            app.CalibrateThresholdButton.Text = "Wait";
            app.StartButton.Enable = "off";

            %% Record audio
            recObj = audiorecorder(app.fs, app.recNBits, app.recNChannels);
            recordingDuration = 2.5;
            recordblocking(recObj, recordingDuration);
            
            %%  Set required parameters
            audio = getaudiodata(recObj);
            dt = 1/app.fs;
            s = recordingDuration/dt;

            %% Apply Groetzel algorithm
            dtmfFrequencies = [697 770 852 941 1209 1336 1477];
            freqIndices = round(dtmfFrequencies/app.fs*s) + 1;   
            dftAudio = goertzel(audio, freqIndices);
            dftAudio = abs(dftAudio');
            
            %% Get max amplitude
            maxMagnitude = max(dftAudio);

            %% Set new threshold
            safeSpaceFactor = 1.1;  % [*100%]
            app.ThresholdEditField.Value = safeSpaceFactor * maxMagnitude;
            app.audioThreshold = safeSpaceFactor * maxMagnitude;

            %% Clean up
            recObj.delete;
            app.CalibrateThresholdButton.Text = "Calibrate Threshold";
            app.StartButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesDFT = uiaxes(app.UIFigure);
            title(app.UIAxesDFT, 'DFT with second-order Goertzel algorithm')
            xlabel(app.UIAxesDFT, 'Frequency [Hz]')
            ylabel(app.UIAxesDFT, 'DFT Magnitude [~]')
            zlabel(app.UIAxesDFT, 'Z')
            app.UIAxesDFT.YLim = [0 1];
            app.UIAxesDFT.XTick = [];
            app.UIAxesDFT.YTick = [];
            app.UIAxesDFT.YScale = 'log';
            app.UIAxesDFT.ZTick = [];
            app.UIAxesTime = uiaxes(app.UIFigure);
            title(app.UIAxesTime, 'Audio file in the time domain')
            xlabel(app.UIAxesTime, 'Time [s]')
            ylabel(app.UIAxesTime, 'Magnitude [~]')
            zlabel(app.UIAxesTime, 'Z')
            app.UIAxesTime.XTick = [];
            app.UIAxesTime.YTick = [];
            app.UIAxesTime.ZTick = [];
            app.CurrentlypressedbuttonEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentlypressedbuttonEditFieldLabel.Text = 'Currently pressed button';
            app.CurrentlypressedbuttonEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentlypressedbuttonEditField.Editable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 10000];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.5;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.Limits = [0 100000];
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditFieldValueChanged, true);
            app.SamplingFrequencyEditField.Value = 16000;
            app.SingleAudioRecordDurationEditFieldLabel = uilabel(app.UIFigure);
            app.SingleAudioRecordDurationEditFieldLabel.Text = 'Single Audio Record Duration';
            app.SingleAudioRecordDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SingleAudioRecordDurationEditField.Limits = [0 1.5];
            app.SingleAudioRecordDurationEditField.ValueChangedFcn = createCallbackFcn(app, @SingleAudioRecordDurationEditFieldValueChanged, true);
            app.SingleAudioRecordDurationEditField.Value = 0.5;
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = '[~]';
            app.FsLabel = uilabel(app.UIFigure);
            app.FsLabel.Text = '[Hz]';
            app.DurationLabel = uilabel(app.UIFigure);
            app.DurationLabel.Text = '[s]';
            app.RecorderSettingsLabel = uilabel(app.UIFigure);
            app.RecorderSettingsLabel.Text = 'Recorder Settings';
            app.DTMFDecoderLabel = uilabel(app.UIFigure);
            app.DTMFDecoderLabel.Text = 'DTMF Decoder';
            app.nBitsDropDownLabel = uilabel(app.UIFigure);
            app.nBitsDropDownLabel.Text = 'n Bits';
            app.nBitsDropDown = uidropdown(app.UIFigure);
            app.nBitsDropDown.Items = {'16', '8', '24'};
            app.nBitsDropDown.ValueChangedFcn = createCallbackFcn(app, @nBitsDropDownValueChanged, true);
            app.nBitsDropDown.Value = '16';
            app.nChannelsDropDownLabel = uilabel(app.UIFigure);
            app.nChannelsDropDownLabel.Text = 'n Channels';
            app.nChannelsDropDown = uidropdown(app.UIFigure);
            app.nChannelsDropDown.Items = {'1', '2'};
            app.nChannelsDropDown.ValueChangedFcn = createCallbackFcn(app, @nChannelsDropDownValueChanged, true);
            app.nChannelsDropDown.Value = '1';
            app.CalibrateThresholdButton = uibutton(app.UIFigure, 'push');
            app.CalibrateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateThresholdButtonPushed, true);
            app.CalibrateThresholdButton.Text = 'Calibrate Threshold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dtmfApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end